classdef matCompare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UITable_Base        matlab.ui.control.Table
        GridLayout          matlab.ui.container.GridLayout
        GridLayout4         matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        GridLayout6         matlab.ui.container.GridLayout
        GridLayout7         matlab.ui.container.GridLayout
        Button_RightSelect  matlab.ui.control.Button
        Button_LeftSelect   matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        UITable_Compare     matlab.ui.control.Table
        GridLayout5         matlab.ui.container.GridLayout
        Button_9            matlab.ui.control.StateButton
        Button_SaveData     matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        GridLayout2         matlab.ui.container.GridLayout
        matPanel_2          matlab.ui.container.Panel
        GridLayout_Right    matlab.ui.container.GridLayout
        UITable_RightData   matlab.ui.control.Table
        Button_2            matlab.ui.control.Button
        matPanel            matlab.ui.container.Panel
        GridLayout_Left     matlab.ui.container.GridLayout
        UITable_LeftData    matlab.ui.control.Table
        Button              matlab.ui.control.Button
        ContextMenu         matlab.ui.container.ContextMenu
        visdiffMenu         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        State   StateEnum
        UserData
        TempUI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startupFunc(app);
        end

        % Button pushed function: Button_SaveData
        function Button_SaveDataPushed(app, event)
            SaveDataButton(app, event);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            SelectLeftButton(app, event);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            SelectRightButton(app, event);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            CompareButton(app, event);
        end

        % Selection changed function: UITable_Base
        function UITable_BaseSelectionChanged(app, event)
            TableSelectColor(app, event);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            OnlyDiffButton(app, event);
        end

        % Button pushed function: Button_LeftSelect
        function Button_LeftSelectPushed(app, event)
            MergeOptionButton(app, event, mergeOptionEnum.left);
        end

        % Button pushed function: Button_RightSelect
        function Button_RightSelectPushed(app, event)
            MergeOptionButton(app, event, mergeOptionEnum.right);
        end

        % Selection changed function: UITable_Compare
        function UITable_CompareSelectionChanged(app, event)
            % CompareTableSelection(app, event);
        end

        % Double-clicked callback: UITable_Compare
        function UITable_CompareDoubleClicked(app, event)
            CompareTableDoubleClicked(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            closeFunc(app, event);
            delete(app);
        end

        % Cell selection callback: UITable_Compare
        function UITable_CompareCellSelection(app, event)
            CompareTableSelection(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.matPanel = uipanel(app.GridLayout2);
            app.matPanel.Title = 'mat文件 左';
            app.GridLayout_Left = uigridlayout(app.matPanel);
            app.Button = uibutton(app.GridLayout_Left, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择文件';
            app.UITable_LeftData = uitable(app.GridLayout_Left);
            app.UITable_LeftData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_LeftData.RowName = {};
            app.UITable_LeftData.SelectionType = 'row';
            app.UITable_LeftData.Enable = 'off';
            app.UITable_LeftData.Visible = 'off';
            app.matPanel_2 = uipanel(app.GridLayout2);
            app.matPanel_2.Title = 'mat文件 右';
            app.GridLayout_Right = uigridlayout(app.matPanel_2);
            app.Button_2 = uibutton(app.GridLayout_Right, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '选择文件';
            app.UITable_RightData = uitable(app.GridLayout_Right);
            app.UITable_RightData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_RightData.RowName = {};
            app.UITable_RightData.SelectionType = 'row';
            app.UITable_RightData.Enable = 'off';
            app.UITable_RightData.Visible = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Button_3 = uibutton(app.GridLayout5, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '比较';
            app.Button_SaveData = uibutton(app.GridLayout5, 'push');
            app.Button_SaveData.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveDataPushed, true);
            app.Button_SaveData.Text = '保存文件';
            app.Button_9 = uibutton(app.GridLayout5, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '仅显示差异项';
            app.Panel = uipanel(app.GridLayout4);
            app.Panel.Title = '合并选项';
            app.GridLayout6 = uigridlayout(app.Panel);
            app.UITable_Compare = uitable(app.GridLayout6);
            app.UITable_Compare.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Compare.RowName = {};
            app.UITable_Compare.SelectionType = 'row';
            app.UITable_Compare.CellSelectionCallback = createCallbackFcn(app, @UITable_CompareCellSelection, true);
            app.UITable_Compare.DoubleClickedFcn = createCallbackFcn(app, @UITable_CompareDoubleClicked, true);
            app.UITable_Compare.SelectionChangedFcn = createCallbackFcn(app, @UITable_CompareSelectionChanged, true);
            app.UITable_Compare.Enable = 'off';
            app.UITable_Compare.Visible = 'off';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.Button_5 = uibutton(app.GridLayout7, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = {'上一差异'; '(不需要了)'};
            app.Button_6 = uibutton(app.GridLayout7, 'push');
            app.Button_6.Text = {'下一差异'; '(不需要了)'};
            app.Button_LeftSelect = uibutton(app.GridLayout7, 'push');
            app.Button_LeftSelect.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftSelectPushed, true);
            app.Button_LeftSelect.Text = '选左';
            app.Button_RightSelect = uibutton(app.GridLayout7, 'push');
            app.Button_RightSelect.ButtonPushedFcn = createCallbackFcn(app, @Button_RightSelectPushed, true);
            app.Button_RightSelect.Text = '选右';
            app.UITable_Base = uitable(app.UIFigure);
            app.UITable_Base.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Base.RowName = {};
            app.UITable_Base.SelectionType = 'row';
            app.UITable_Base.SelectionChangedFcn = createCallbackFcn(app, @UITable_BaseSelectionChanged, true);
            app.UITable_Base.Enable = 'off';
            app.UITable_Base.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.visdiffMenu = uimenu(app.ContextMenu);
            app.visdiffMenu.Text = '使用visdiff比较';
            % Assign app.ContextMenu
            app.UITable_Compare.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matCompare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end